classdef gith < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ImportSequenceButton        matlab.ui.control.Button
        SequenceEditField           matlab.ui.control.EditField
        SequenceEditFieldLabel      matlab.ui.control.Label
        InstantPostButton           matlab.ui.control.StateButton
        debugButton                 matlab.ui.control.Button
        RPIIPAddressEditField       matlab.ui.control.EditField
        RPIIPAddressEditFieldLabel  matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        Panel                       matlab.ui.container.Panel
        Tree                        matlab.ui.container.Tree
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        MANUALVALVEPOSTButton       matlab.ui.control.Button
        GridLayout3                 matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        IGNITEButton                matlab.ui.control.Button
        LOXPurgeButton              matlab.ui.control.StateButton
        FUELPurgeButton             matlab.ui.control.StateButton
        MAINButton                  matlab.ui.control.StateButton
        LOXVentButton               matlab.ui.control.StateButton
        FUELVentButton              matlab.ui.control.StateButton
        LOXPressButton              matlab.ui.control.StateButton
        FUELPressButton             matlab.ui.control.StateButton
        ManualValveControlLabel     matlab.ui.control.Label
        ABORTButton                 matlab.ui.control.Button
        SystemStateButtonGroup      matlab.ui.container.ButtonGroup
        FireSequenceButton          matlab.ui.control.ToggleButton
        TestSequenceButton          matlab.ui.control.ToggleButton
        SafeButton                  matlab.ui.control.ToggleButton
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        instantPost; % Toggles instant button posting to http
        desiredState; % weird fix?? POP
        sequenceString; % cringe
    end
    
    methods (Access = public)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.desiredState = StateMachine();
            value = app.InstantPostButton.Value;
            app.instantPost = value;
            app.desiredState.ip = app.RPIIPAddressEditField.Value;
        end

        % Value changed function: RPIIPAddressEditField
        function RPIIPAddressEditFieldValueChanged(app, event)
            value = app.RPIIPAddressEditField.Value;
            app.desiredState.ip = value;
        end

        % Value changed function: FUELPressButton
        function FUELPressButtonValueChanged(app, event)
            value = app.FUELPressButton.Value;
            app.desiredState.FUEL_Press = value;
            
            if(app.instantPost)
                app.desiredState.post()
            end
        end

        % Value changed function: LOXPressButton
        function LOXPressButtonValueChanged(app, event)
            value = app.LOXPressButton.Value;
            app.desiredState.LOX_Press = value;
            
            if(app.instantPost)
                app.desiredState.post()
            end
        end

        % Callback function
        function InstantValvePostSwitchValueChanged(app, event)
            value = app.InstantValvePostSwitch.Value;
            app.instantPost = value;
        end

        % Button pushed function: MANUALVALVEPOSTButton
        function MANUALVALVEPOSTButtonPushed(app, event)
            app.desiredState.post()
        end

        % Value changed function: FUELVentButton
        function FUELVentButtonValueChanged(app, event)
            value = app.FUELVentButton.Value;
            app.desiredState.FUEL_Vent = value;
            
            if(app.instantPost)
                app.desiredState.post()
            end
        end

        % Value changed function: LOXVentButton
        function LOXVentButtonValueChanged(app, event)
            value = app.LOXVentButton.Value;
            app.desiredState.LOX_Vent = value;
            
            if(app.instantPost)
                app.desiredState.post()
            end
        end

        % Value changed function: MAINButton
        function MAINButtonValueChanged(app, event)
            value = app.MAINButton.Value;
            app.desiredState.MAIN = value;
            
            if(app.instantPost)
                app.desiredState.post()
            end
        end

        % Value changed function: FUELPurgeButton
        function FUELPurgeButtonValueChanged(app, event)
            value = app.FUELPurgeButton.Value;
            app.desiredState.FUEL_Purge = value;
            
            if(app.instantPost)
                app.desiredState.post()
            end
        end

        % Value changed function: LOXPurgeButton
        function LOXPurgeButtonValueChanged(app, event)
            value = app.LOXPurgeButton.Value;
            app.desiredState.LOX_Purge = value;
            
            if(app.instantPost)
                app.desiredState.post()
            end
        end

        % Button pushed function: debugButton
        function debugButtonPushed(app, event)
            print(app.desiredState)
        end

        % Callback function
        function InstaPostToggleSwitchValueChanged(app, event)
            value = app.InstaPostToggleSwitch.Value;
            app.instantPost = value;
        end

        % Value changed function: InstantPostButton
        function InstantPostButtonValueChanged(app, event)
            value = app.InstantPostButton.Value;
            app.instantPost = value;
        end

        % Button pushed function: ImportSequenceButton
        function ImportSequenceButtonPushed(app, event)
            app.desiredState.parseSequence(app.SequenceEditField.Value);
            %disp(app.desiredState.sequence);
            
            % show nodes
%             n1 = uitreenode(app.Tree,'Text','App 1');
%             n2 = uitreenode(app.Tree,'Text','App 2');
%             n3 = uitreenode(app.Tree,'Text','Images');
%             
%             n11 = uitreenode(n1,'Text','myapp1.m');
%             n21 = uitreenode(n2,'Text','myapp2.m');
%             n22 = uitreenode(n2,'Text','app2callback.m');
%             n31 = uitreenode(n3,'Text','peppers.png');
%             n32 = uitreenode(n3,'Text','corn.tif');

            names = fieldnames(app.desiredState.sequence);
            seq = app.desiredState.sequence;
            firstLevel = {};
            
            for i = 1:length(names)
                %disp(names{i})
                firstLevel{i} = uitreenode( app.Tree,'Text', getfield(seq, names{i}).Name );
                disp(firstLevel)
                disp(firstLevel{i})
                a = uitreenode( firstLevel{i},'Text', sprintf('Duration: %s s', num2str(getfield(seq, names{i}).Duration) ) );
                b = uitreenode( firstLevel{i},'Text','State');
                
                KeyList = {
                    'FUEL_Press',
                    'LOX_Press',
                    'FUEL_Vent',
                    'LOX_Vent',
                    'MAIN',
                    'FUEL_Purge',
                    'LOX_Purge'
                };
                
                state = getfield(seq, names{i}).State;
                for j = 1:length(KeyList)
                    c = uitreenode (b,'Text', sprintf('%s: %s', KeyList{j}, num2str(getfield(state,KeyList{j})) ))
                end
                
            end
%             a = uitreenode( firstLevel(i),'Text', getfield(seq, names{i}).Duration );
            
            expand(app.Tree)
        end

        % Value changed function: SequenceEditField
        function SequenceEditFieldValueChanged(app, event)
            %value = str(app.SequenceEditField.Value); % wEiRd FiX
            %app.sequenceString = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {837, 837, 837};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {837, 837};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {282, '1x', 267};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RPIIPAddressEditFieldLabel = uilabel(app.LeftPanel);
            app.RPIIPAddressEditFieldLabel.Text = 'RPI IP Address';
            app.RPIIPAddressEditField = uieditfield(app.LeftPanel, 'text');
            app.RPIIPAddressEditField.ValueChangedFcn = createCallbackFcn(app, @RPIIPAddressEditFieldValueChanged, true);
            app.RPIIPAddressEditField.Value = '192.168.137.10';
            app.debugButton = uibutton(app.LeftPanel, 'push');
            app.debugButton.ButtonPushedFcn = createCallbackFcn(app, @debugButtonPushed, true);
            app.debugButton.Text = 'debug';
            app.InstantPostButton = uibutton(app.LeftPanel, 'state');
            app.InstantPostButton.ValueChangedFcn = createCallbackFcn(app, @InstantPostButtonValueChanged, true);
            app.InstantPostButton.Text = 'Instant Post';
            app.SequenceEditFieldLabel = uilabel(app.LeftPanel);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceEditField = uieditfield(app.LeftPanel, 'text');
            app.SequenceEditField.ValueChangedFcn = createCallbackFcn(app, @SequenceEditFieldValueChanged, true);
            app.SequenceEditField.Value = 'sequence.json';
            app.ImportSequenceButton = uibutton(app.LeftPanel, 'push');
            app.ImportSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSequenceButtonPushed, true);
            app.ImportSequenceButton.Text = 'Import Sequence';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.CenterPanel);
            app.Panel.Title = 'Panel';
            app.Tree = uitree(app.Panel);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.SystemStateButtonGroup = uibuttongroup(app.GridLayout2);
            app.SystemStateButtonGroup.Title = 'System State';
            app.SafeButton = uitogglebutton(app.SystemStateButtonGroup);
            app.SafeButton.Text = 'Safe';
            app.SafeButton.Value = true;
            app.TestSequenceButton = uitogglebutton(app.SystemStateButtonGroup);
            app.TestSequenceButton.Text = 'Test Sequence';
            app.FireSequenceButton = uitogglebutton(app.SystemStateButtonGroup);
            app.FireSequenceButton.Text = 'Fire Sequence';
            app.ABORTButton = uibutton(app.GridLayout2, 'push');
            app.ABORTButton.Text = 'ABORT';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ManualValveControlLabel = uilabel(app.GridLayout3);
            app.ManualValveControlLabel.Text = 'Manual Valve Control';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.FUELPressButton = uibutton(app.GridLayout4, 'state');
            app.FUELPressButton.ValueChangedFcn = createCallbackFcn(app, @FUELPressButtonValueChanged, true);
            app.FUELPressButton.Text = 'FUEL-Press';
            app.LOXPressButton = uibutton(app.GridLayout4, 'state');
            app.LOXPressButton.ValueChangedFcn = createCallbackFcn(app, @LOXPressButtonValueChanged, true);
            app.LOXPressButton.Text = 'LOX-Press';
            app.FUELVentButton = uibutton(app.GridLayout4, 'state');
            app.FUELVentButton.ValueChangedFcn = createCallbackFcn(app, @FUELVentButtonValueChanged, true);
            app.FUELVentButton.Text = 'FUEL-Vent';
            app.FUELVentButton.Value = true;
            app.LOXVentButton = uibutton(app.GridLayout4, 'state');
            app.LOXVentButton.ValueChangedFcn = createCallbackFcn(app, @LOXVentButtonValueChanged, true);
            app.LOXVentButton.Text = 'LOX-Vent';
            app.LOXVentButton.Value = true;
            app.MAINButton = uibutton(app.GridLayout4, 'state');
            app.MAINButton.ValueChangedFcn = createCallbackFcn(app, @MAINButtonValueChanged, true);
            app.MAINButton.Text = 'MAIN';
            app.FUELPurgeButton = uibutton(app.GridLayout4, 'state');
            app.FUELPurgeButton.ValueChangedFcn = createCallbackFcn(app, @FUELPurgeButtonValueChanged, true);
            app.FUELPurgeButton.Text = 'FUEL-Purge';
            app.LOXPurgeButton = uibutton(app.GridLayout4, 'state');
            app.LOXPurgeButton.ValueChangedFcn = createCallbackFcn(app, @LOXPurgeButtonValueChanged, true);
            app.LOXPurgeButton.Text = 'LOX-Purge';
            app.IGNITEButton = uibutton(app.GridLayout4, 'push');
            app.IGNITEButton.Text = 'IGNITE';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.MANUALVALVEPOSTButton = uibutton(app.GridLayout5, 'push');
            app.MANUALVALVEPOSTButton.ButtonPushedFcn = createCallbackFcn(app, @MANUALVALVEPOSTButtonPushed, true);
            app.MANUALVALVEPOSTButton.Text = 'MANUAL VALVE POST';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gith

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end